classdef Vehicle_Portfolio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COCAVehiclePortfolioUIFigure  matlab.ui.Figure
        MeasurementValuesLabel        matlab.ui.control.Label
        Panel_Vehicle_Dynamics        matlab.ui.container.Panel
        Turning_Cycle                 matlab.ui.control.NumericEditField
        Maximum_Speed                 matlab.ui.control.NumericEditField
        Acceleration                  matlab.ui.control.NumericEditField
        Acceleration_Label            matlab.ui.control.Label
        TurningCycle_Label            matlab.ui.control.Label
        Maximumspeed_Label            matlab.ui.control.Label
        VehicleDynamicsLabel          matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        kmhLabel                      matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        Panel_Suitability             matlab.ui.container.Panel
        TrunkvolumeLabel              matlab.ui.control.Label
        RangeLabel                    matlab.ui.control.Label
        ChargingtimeLabel             matlab.ui.control.Label
        NumberofseatsLabel            matlab.ui.control.Label
        PayloadLabel                  matlab.ui.control.Label
        EditField_25                  matlab.ui.control.NumericEditField
        EditField_26                  matlab.ui.control.NumericEditField
        EditField_27                  matlab.ui.control.NumericEditField
        EditField_28                  matlab.ui.control.NumericEditField
        EditField_29                  matlab.ui.control.NumericEditField
        SuitabilityLabel              matlab.ui.control.Label
        LiterLabel                    matlab.ui.control.Label
        kmLabel                       matlab.ui.control.Label
        minLabel                      matlab.ui.control.Label
        Label_17                      matlab.ui.control.Label
        kgLabel                       matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        LengthLabel                   matlab.ui.control.Label
        WidthLabel                    matlab.ui.control.Label
        HeightLabel                   matlab.ui.control.Label
        WheelbaseLabel                matlab.ui.control.Label
        HeadroomfrontLabel            matlab.ui.control.Label
        HeadroomrearLabel             matlab.ui.control.Label
        WheeldiameterLabel            matlab.ui.control.Label
        EditField_17                  matlab.ui.control.NumericEditField
        EditField_18                  matlab.ui.control.NumericEditField
        EditField_19                  matlab.ui.control.NumericEditField
        EditField_20                  matlab.ui.control.NumericEditField
        EditField_21                  matlab.ui.control.NumericEditField
        EditField_22                  matlab.ui.control.NumericEditField
        EditField_23                  matlab.ui.control.NumericEditField
        DimensionsLabel               matlab.ui.control.Label
        mmLabel                       matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        mmLabel_10                    matlab.ui.control.Label
        mmLabel_6                     matlab.ui.control.Label
        mmLabel_5                     matlab.ui.control.Label
        mmLabel_4                     matlab.ui.control.Label
        mmLabel_3                     matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        HeadroomfrontLabel_2          matlab.ui.control.Label
        HeadroomrearLabel_2           matlab.ui.control.Label
        LegroomrearLabel              matlab.ui.control.Label
        AcousticsLabel                matlab.ui.control.Label
        EditField_9                   matlab.ui.control.NumericEditField
        EditField_10                  matlab.ui.control.NumericEditField
        EditField_11                  matlab.ui.control.NumericEditField
        EditField_12                  matlab.ui.control.NumericEditField
        ComfortLabel                  matlab.ui.control.Label
        mmLabel_7                     matlab.ui.control.Label
        mmLabel_8                     matlab.ui.control.Label
        mmLabel_9                     matlab.ui.control.Label
        dBLabel                       matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        WeightLabel                   matlab.ui.control.Label
        ConsumptionLabel              matlab.ui.control.Label
        AssetcostsLabel               matlab.ui.control.Label
        EditField_30                  matlab.ui.control.NumericEditField
        EditField_31                  matlab.ui.control.NumericEditField
        EditField_32                  matlab.ui.control.NumericEditField
        CostsLabel                    matlab.ui.control.Label
        kgLabel_2                     matlab.ui.control.Label
        kmLabel_2                     matlab.ui.control.Label
        Label_25                      matlab.ui.control.Label
    end


    properties (Access = public)
        TD
        Pattern
        Weightings
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TD, Weightings, Pattern)
         %%%%Description: This function assigns the values previously calculated to the vehicle portfolio GUI
                  
            app.TD = TD;
            app.Weightings = Weightings;
            app.Pattern = Pattern;
            
            % Set the Value for the acceleration in the GUI
            app.Acceleration.Limits = [2, 20];
                if app.TD(4) < app.Acceleration.Limits(1,1)
                    app.TD(4) = app.Acceleration.Limits(1,1);
                    
                elseif app.TD(4) > app.Acceleration.Limits(1,2) || isnan(app.TD(4))
                    
                    app.TD(4) = app.Acceleration.Limits(1,2);
                end
            app.Acceleration.Value = app.TD(4);
            
            % Set the Value for the top speed in the GUI
            app.Maximum_Speed.Limits = [40, 350];
                if app.TD(5) < app.Maximum_Speed.Limits(1,1)
                    app.TD(5) = app.Maximum_Speed.Limits(1,1);
                elseif app.TD(5) > app.Maximum_Speed.Limits(1,2) || isnan(app.TD(5))
                    app.TD(5) = app.Maximum_Speed.Limits(1,2);
                end
            app.Maximum_Speed.Value = app.TD(5);
            
            % Set the Value for the turning circle in the GUI
            app.Turning_Cycle.Limits = [3, 14];
                if app.TD(6) < app.Turning_Cycle.Limits(1,1)
                    app.TD(6) = app.Turning_Cycle.Limits(1,1);
                elseif app.TD(6) > app.Turning_Cycle.Limits(1,2) || isnan(app.TD(6))
                    app.TD(6) = app.Turning_Cycle.Limits(1,2);
                end
            app.Turning_Cycle.Value = app.TD(6);
            
            % Set the Value for the Vehicle lenght in the GUI
            app.EditField_17.Limits = [2200, 5900];
                if app.TD(7) < app.EditField_17.Limits(1,1)
                    app.TD(7) = app.EditField_17.Limits(1,1);
                elseif app.TD(7) > app.EditField_17.Limits(1,2) || isnan(app.TD(7))
                    app.TD(7) = app.EditField_17.Limits(1,2);
                end
            app.EditField_17.Value = app.TD(7);
            
            % Set the Value for the vehicle width in the GUI
            app.EditField_18.Limits = [1150, 2050];
                if app.TD(8) < app.EditField_18.Limits(1,1) 
                    app.TD(8) = app.EditField_18.Limits(1,1);
                elseif app.TD(8) > app.EditField_18.Limits(1,2) || isnan(app.TD(8))
                    app.TD(8) = app.EditField_18.Limits(1,2);
                end
            app.EditField_18.Value = app.TD(8);
            
            % Set the Value for the vehicle height in the GUI
            app.EditField_19.Limits = [1100, 2000];
                if app.TD(9) < app.EditField_19.Limits(1,1) || isnan(app.TD(9))
                    app.TD(9) = app.EditField_19.Limits(1,1);
                elseif app.TD(9) > app.EditField_19.Limits(1,2) 
                    app.TD(9) = app.EditField_19.Limits(1,2);
                end
            app.EditField_19.Value = app.TD(9);
            
            % Set the Value for the wheelbase in the GUI
            app.EditField_20.Limits = [1600, 3400];
                if app.TD(10) < app.EditField_20.Limits(1,1)
                    app.TD(10) = app.EditField_20.Limits(1,1);
                elseif app.TD(10) > app.EditField_20.Limits(1,2) || isnan(app.TD(10))
                    app.TD(10) = app.EditField_20.Limits(1,2);
                end
            app.EditField_20.Value = app.TD(10);
            
            % Set the Value for the overhang front in the GUI
            app.EditField_21.Limits = [300, 1100];
                if app.TD(11) < app.EditField_21.Limits(1,1)
                    app.TD(11) = app.EditField_21.Limits(1,1);
                elseif app.TD(11) > app.EditField_21.Limits(1,2) || isnan(app.TD(11))
                    app.TD(11) = app.EditField_21.Limits(1,2);
                end
            app.EditField_21.Value = app.TD(11);
            
            % Set the Value for the overhang rear in the GUI
            app.EditField_22.Limits = [300, 1200];
                if app.TD(12) < app.EditField_22.Limits(1,1)
                    app.TD(12) = app.EditField_22.Limits(1,1);
                elseif app.TD(12) > app.EditField_22.Limits(1,2) || isnan(app.TD(12))
                    app.TD(12) = app.EditField_22.Limits(1,2);
                end
            app.EditField_22.Value = app.TD(12);
            
            % Set the Value for the wheel diameter in the GUI
            app.EditField_23.Limits = [530, 770];
                if app.TD(13) < app.EditField_23.Limits(1,1)
                    app.TD(13) = app.EditField_23.Limits(1,1);
                elseif app.TD(13) > app.EditField_23.Limits(1,2) || isnan(app.TD(13))
                    app.TD(13) = app.EditField_23.Limits(1,2);
                end
            app.EditField_23.Value = app.TD(13);
             
            % Set the Value for the trunk volume in the GUI
            app.EditField_25.Limits = [10, 900];
                if app.TD(14) < app.EditField_25.Limits(1,1)
                    app.TD(14) = app.EditField_25.Limits(1,1);
                elseif app.TD(14) > app.EditField_25.Limits(1,2) || isnan(app.TD(14))
                    app.TD(14) = app.EditField_25.Limits(1,2);
                end
            app.EditField_25.Value = app.TD(14);
            
            % Set the Value for the range in the GUI
            app.EditField_26.Limits = [0, 1500];
                if app.TD(15) < app.EditField_26.Limits(1,1)
                    app.TD(15) = app.EditField_26.Limits(1,1);
                elseif app.TD(15) > app.EditField_26.Limits(1,2) || isnan(app.TD(15))
                    app.TD(15) = app.EditField_26.Limits(1,2);
                end
            app.EditField_26.Value = app.TD(15);
            
            % Set the Value for the charging time in the GUI
            app.EditField_27.Limits = [0, 300];
                if app.TD(16) < app.EditField_27.Limits(1,1)
                    app.TD(16) = app.EditField_27.Limits(1,1);
                elseif app.TD(16) > app.EditField_27.Limits(1,2) || isnan(app.TD(16))
                    app.TD(16) = app.EditField_27.Limits(1,2);
                end
            app.EditField_27.Value = app.TD(16);
            
            % Set the Value for the number of seats in the GUI
            app.EditField_28.Limits = [1, 9];
                if app.TD(17) < app.EditField_28.Limits(1,1)
                    app.TD(17) = app.EditField_28.Limits(1,1);
                elseif app.TD(17) > app.EditField_28.Limits(1,2) || isnan(app.TD(17))
                    app.TD(17) = app.EditField_28.Limits(1,2);
                end
            app.EditField_28.Value = app.TD(17);
            
            % Set the Value for the payload in the GUI
            app.EditField_29.Limits = [100, 1300];
                if app.TD(18) < app.EditField_29.Limits(1,1)
                    app.TD(18) = app.EditField_29.Limits(1,1);
                elseif app.TD(18) > app.EditField_29.Limits(1,2) || isnan(app.TD(18))
                    app.TD(18) = app.EditField_29.Limits(1,2);
                end
            app.EditField_29.Value = app.TD(18);
            
            % Set the Value for the headroom front in the GUI
            app.EditField_9.Limits = [700, 1200];
                if app.TD(19) < app.EditField_9.Limits(1,1)
                    app.TD(19) = app.EditField_9.Limits(1,1);
                elseif app.TD(19) > app.EditField_9.Limits(1,2) || isnan(app.TD(19))
                    app.TD(19) = app.EditField_9.Limits(1,2);
                end
            app.EditField_9.Value = app.TD(19);
            
            % Set the Value for the headroom rear in the GUI
            app.EditField_10.Limits = [0, 1200];
                if app.TD(20) < app.EditField_10.Limits(1,1)
                    app.TD(20) = app.EditField_10.Limits(1,1);
                elseif app.TD(20) > app.EditField_10.Limits(1,2) || isnan(app.TD(20))
                    app.TD(20) = app.EditField_10.Limits(1,2);
                end
            app.EditField_10.Value = app.TD(20);
            
            % Set the Value for the legroom in the GUI
            app.EditField_11.Limits = [0, 1200];
                if app.TD(21) < app.EditField_11.Limits(1,1)
                    app.TD(21) = app.EditField_11.Limits(1,1);
                elseif app.TD(21) > app.EditField_11.Limits(1,2) || isnan(app.TD(21))
                    app.TD(21) = app.EditField_11.Limits(1,2);
                end
            app.EditField_11.Value = app.TD(21);
            
            % EditField_12 Acoustics
            app.EditField_12.Limits = [0, 120];
                if app.TD(22) < app.EditField_12.Limits(1,1)
                    app.TD(22) = app.EditField_12.Limits(1,1);
                elseif app.TD(22) > app.EditField_12.Limits(1,2) || isnan(app.TD(22))
                    app.TD(22) = app.EditField_12.Limits(1,2);
                end
            app.EditField_12.Value = app.TD(22);
                        
            % Set the Value for the weight in the GUI
            app.EditField_30.Limits = [500, 2700];
                if app.TD(23) < app.EditField_30.Limits(1,1)
                    app.TD(23) = app.EditField_30.Limits(1,1);
                elseif app.TD(23) > app.EditField_30.Limits(1,2) || isnan(app.TD(23))
                    app.TD(23) = app.EditField_30.Limits(1,2);
                end
            app.EditField_30.Value = app.TD(23);
            
            % Set the Value for the consumption in the GUI
            app.EditField_31.Limits = [0, 15];
                if app.TD(24) < app.EditField_31.Limits(1,1)
                    app.TD(24) = app.EditField_31.Limits(1,1);
                elseif app.TD(24) > app.EditField_31.Limits(1,2) || isnan(app.TD(24))
                    app.TD(24) = app.EditField_31.Limits(1,2);
                end
            app.EditField_31.Value = app.TD(24);
            
            % Set the Value for the asset costs in the GUI
            app.EditField_32.Limits = [6500, 500000];
                if app.TD(25) < app.EditField_32.Limits(1,1) || isnan(app.TD(25))
                    app.TD(25) = app.EditField_32.Limits(1,1);
                elseif app.TD(25) > app.EditField_32.Limits(1,2) 
                    app.TD(25) = app.EditField_32.Limits(1,2);
                end
            app.EditField_32.Value = app.TD(25);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COCAVehiclePortfolioUIFigure = uifigure;
            app.COCAVehiclePortfolioUIFigure.Name = 'COCA - Vehicle Portfolio';
            app.MeasurementValuesLabel = uilabel(app.COCAVehiclePortfolioUIFigure);
            app.MeasurementValuesLabel.Text = 'Measurement Values';
            app.Panel_Vehicle_Dynamics = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.Turning_Cycle = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Turning_Cycle.ValueDisplayFormat = '%.1f';
            app.Maximum_Speed = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Maximum_Speed.ValueDisplayFormat = '%.0f';
            app.Acceleration = uieditfield(app.Panel_Vehicle_Dynamics, 'numeric');
            app.Acceleration.ValueDisplayFormat = '%.1f';
            app.Acceleration_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.Acceleration_Label.Text = 'Acceleration (0-100)';
            app.TurningCycle_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.TurningCycle_Label.Text = 'Turning Cycle';
            app.Maximumspeed_Label = uilabel(app.Panel_Vehicle_Dynamics);
            app.Maximumspeed_Label.Text = 'Maximum speed';
            app.VehicleDynamicsLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.VehicleDynamicsLabel.Text = 'Vehicle Dynamics';
            app.sLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.sLabel.Text = 's';
            app.kmhLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.kmhLabel.Text = 'km/h';
            app.mLabel = uilabel(app.Panel_Vehicle_Dynamics);
            app.mLabel.Text = 'm';
            app.Panel_Suitability = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.TrunkvolumeLabel = uilabel(app.Panel_Suitability);
            app.TrunkvolumeLabel.Text = 'Trunk volume';
            app.RangeLabel = uilabel(app.Panel_Suitability);
            app.RangeLabel.Text = 'Range';
            app.ChargingtimeLabel = uilabel(app.Panel_Suitability);
            app.ChargingtimeLabel.Text = 'Charging time';
            app.NumberofseatsLabel = uilabel(app.Panel_Suitability);
            app.NumberofseatsLabel.Text = 'Number of seats';
            app.PayloadLabel = uilabel(app.Panel_Suitability);
            app.PayloadLabel.Text = 'Payload';
            app.EditField_25 = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_25.ValueDisplayFormat = '%.0f';
            app.EditField_26 = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_26.ValueDisplayFormat = '%.0f';
            app.EditField_27 = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_27.ValueDisplayFormat = '%.0f';
            app.EditField_28 = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_28.ValueDisplayFormat = '%.0f';
            app.EditField_29 = uieditfield(app.Panel_Suitability, 'numeric');
            app.EditField_29.ValueDisplayFormat = '%.0f';
            app.SuitabilityLabel = uilabel(app.Panel_Suitability);
            app.SuitabilityLabel.Text = 'Suitability';
            app.LiterLabel = uilabel(app.Panel_Suitability);
            app.LiterLabel.Text = 'Liter';
            app.kmLabel = uilabel(app.Panel_Suitability);
            app.kmLabel.Text = 'km';
            app.minLabel = uilabel(app.Panel_Suitability);
            app.minLabel.Text = 'min';
            app.Label_17 = uilabel(app.Panel_Suitability);
            app.Label_17.Text = '-';
            app.kgLabel = uilabel(app.Panel_Suitability);
            app.kgLabel.Text = 'kg';
            app.Panel_3 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.LengthLabel = uilabel(app.Panel_3);
            app.LengthLabel.Text = 'Length';
            app.WidthLabel = uilabel(app.Panel_3);
            app.WidthLabel.Text = 'Width';
            app.HeightLabel = uilabel(app.Panel_3);
            app.HeightLabel.Text = 'Height';
            app.WheelbaseLabel = uilabel(app.Panel_3);
            app.WheelbaseLabel.Text = 'Wheelbase';
            app.HeadroomfrontLabel = uilabel(app.Panel_3);
            app.HeadroomfrontLabel.Text = 'Headroom front';
            app.HeadroomrearLabel = uilabel(app.Panel_3);
            app.HeadroomrearLabel.Text = 'Headroom rear';
            app.WheeldiameterLabel = uilabel(app.Panel_3);
            app.WheeldiameterLabel.Text = 'Wheel diameter';
            app.EditField_17 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_17.ValueDisplayFormat = '%.0f';
            app.EditField_18 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_18.ValueDisplayFormat = '%.0f';
            app.EditField_19 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_19.ValueDisplayFormat = '%.0f';
            app.EditField_20 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_20.ValueDisplayFormat = '%.0f';
            app.EditField_21 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_21.ValueDisplayFormat = '%.0f';
            app.EditField_22 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_22.ValueDisplayFormat = '%.0f';
            app.EditField_23 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_23.ValueDisplayFormat = '%.0f';
            app.DimensionsLabel = uilabel(app.Panel_3);
            app.DimensionsLabel.Text = 'Dimensions';
            app.mmLabel = uilabel(app.Panel_3);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.Panel_3);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_10 = uilabel(app.Panel_3);
            app.mmLabel_10.Text = 'mm';
            app.mmLabel_6 = uilabel(app.Panel_3);
            app.mmLabel_6.Text = 'mm';
            app.mmLabel_5 = uilabel(app.Panel_3);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_4 = uilabel(app.Panel_3);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_3 = uilabel(app.Panel_3);
            app.mmLabel_3.Text = 'mm';
            app.Panel_2 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.HeadroomfrontLabel_2 = uilabel(app.Panel_2);
            app.HeadroomfrontLabel_2.Text = 'Headroom front';
            app.HeadroomrearLabel_2 = uilabel(app.Panel_2);
            app.HeadroomrearLabel_2.Text = 'Headroom rear';
            app.LegroomrearLabel = uilabel(app.Panel_2);
            app.LegroomrearLabel.Text = 'Legroom rear';
            app.AcousticsLabel = uilabel(app.Panel_2);
            app.AcousticsLabel.Text = 'Acoustics';
            app.EditField_9 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_10 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%.0f';
            app.EditField_11 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.0f';
            app.EditField_12 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%.1f';
            app.ComfortLabel = uilabel(app.Panel_2);
            app.ComfortLabel.Text = 'Comfort';
            app.mmLabel_7 = uilabel(app.Panel_2);
            app.mmLabel_7.Text = 'mm';
            app.mmLabel_8 = uilabel(app.Panel_2);
            app.mmLabel_8.Text = 'mm';
            app.mmLabel_9 = uilabel(app.Panel_2);
            app.mmLabel_9.Text = 'mm';
            app.dBLabel = uilabel(app.Panel_2);
            app.dBLabel.Text = 'dB';
            app.Panel_5 = uipanel(app.COCAVehiclePortfolioUIFigure);
            app.WeightLabel = uilabel(app.Panel_5);
            app.WeightLabel.Text = 'Weight';
            app.ConsumptionLabel = uilabel(app.Panel_5);
            app.ConsumptionLabel.Text = 'Consumption';
            app.AssetcostsLabel = uilabel(app.Panel_5);
            app.AssetcostsLabel.Text = 'Asset costs';
            app.EditField_30 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_30.ValueDisplayFormat = '%.0f';
            app.EditField_31 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_31.Limits = [0 Inf];
            app.EditField_31.ValueDisplayFormat = '%.1f';
            app.EditField_32 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_32.ValueDisplayFormat = '%.0f';
            app.CostsLabel = uilabel(app.Panel_5);
            app.CostsLabel.Text = 'Costs';
            app.kgLabel_2 = uilabel(app.Panel_5);
            app.kgLabel_2.Text = 'kg';
            app.kmLabel_2 = uilabel(app.Panel_5);
            app.kmLabel_2.Text = '€/100 km';
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '€';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vehicle_Portfolio(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COCAVehiclePortfolioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COCAVehiclePortfolioUIFigure)
        end
    end
end